classdef ClientGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioServerClientGUIUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlaybackTab                   matlab.ui.container.Tab
        PlaybackTypeSwitch            matlab.ui.control.Switch
        FromFilesPanel                matlab.ui.container.Panel
        GridLayoutFromFiles           matlab.ui.container.GridLayout
        FromFilesPanelInfo            matlab.ui.control.Label
        PlaybackRefreshListsButton    matlab.ui.control.Button
        FilterPanel                   matlab.ui.container.Panel
        GridLayoutPlaybackFilter      matlab.ui.container.GridLayout
        FilterAudioFilesButton        matlab.ui.control.StateButton
        FilterPlaylistsButton         matlab.ui.control.StateButton
        FromFileDropDown              matlab.ui.control.DropDown
        PlayButtonFromFiles           matlab.ui.control.StateButton
        PlaybackFromFilesLogs         matlab.ui.control.TextArea
        GeneratePanel                 matlab.ui.container.Panel
        GridLayoutGenerate            matlab.ui.container.GridLayout
        GeneratePanelInfo             matlab.ui.control.Label
        GenerateTypePanel             matlab.ui.container.Panel
        GridLayoutGenerateType        matlab.ui.container.GridLayout
        GenerateToneTypeButton        matlab.ui.control.StateButton
        GenerateSweepTypeButton       matlab.ui.control.StateButton
        FrequencyHzLabel              matlab.ui.control.Label
        FrequencyHzSpinner            matlab.ui.control.Spinner
        EndFrequencyHzLabel           matlab.ui.control.Label
        EndFrequencyHzSpinner         matlab.ui.control.Spinner
        DurationmsSpinner             matlab.ui.control.Spinner
        AmplitudedBSpinner            matlab.ui.control.Spinner
        SampleRateHzSpinner           matlab.ui.control.Spinner
        EdgesmsSpinner                matlab.ui.control.Spinner
        EdgesmsSpinnerLabel           matlab.ui.control.Label
        SampleRateHzSpinnerLabel      matlab.ui.control.Label
        AmplitudedBSpinnerLabel       matlab.ui.control.Label
        DurationmsSpinnerLabel        matlab.ui.control.Label
        EdgesmsHelp                   matlab.ui.control.Label
        SweepFunctionLabel            matlab.ui.control.Label
        SweepFnDropDown               matlab.ui.control.DropDown
        PlayButtonGenerateAudio       matlab.ui.control.StateButton
        PlaybackGenerateAudioLog      matlab.ui.control.TextArea
        CreateTab                     matlab.ui.container.Tab
        InformationTab                matlab.ui.container.Tab
        SettingsTab                   matlab.ui.container.Tab
        GridLayoutSettings            matlab.ui.container.GridLayout
        ServerIPPortEditField         matlab.ui.control.EditField
        ServerIPPortLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        AudioFilesList % Collection of Available Audio Files
        PlaylistsList % Collection of Available (Validated) Playlists
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format longG
        end

        % Value changed function: PlaybackTypeSwitch
        function PlaybackTypeSwitchValueChanged(app, event)
            value = app.PlaybackTypeSwitch.Value;
            if value == "From Files"
                app.FromFilesPanel.Enable = "on";
                app.GeneratePanel.Enable = "off";
            elseif value == "Generate"
                app.GeneratePanel.Enable = "on";
                app.FromFilesPanel.Enable = "off";
            end
        end

        % Value changed function: FilterAudioFilesButton
        function FilterAudioFilesButtonValueChanged(app, event)
            if app.FilterPlaylistsButton.Value == 0
                app.FilterAudioFilesButton.Value = 1;
                return
            end
            app.FilterPlaylistsButton.Value = 0;
            app.FilterAudioFilesButton.Value = 1;

            % Filter for only Audio Files and display on dropdown list
            % Update the current active Filter Dropdown
            app.FromFileDropDown.Items = app.AudioFilesList;
            app.FromFileDropDown.Value = app.AudioFilesList(1);
        end

        % Value changed function: FilterPlaylistsButton
        function FilterPlaylistsButtonValueChanged(app, event)
            if app.FilterAudioFilesButton.Value == 0
                app.FilterPlaylistsButton.Value = 1;
                return
            end

            app.FilterAudioFilesButton.Value = 0;
            app.FilterPlaylistsButton.Value = 1;

            % Filter for only Playlists and display on dropdown list
            % Update the current active Filter Dropdown
            app.FromFileDropDown.Items = app.PlaylistsList;
            app.FromFileDropDown.Value = app.PlaylistsList(1);
        end

        % Value changed function: GenerateToneTypeButton
        function GenerateToneTypeButtonValueChanged(app, event)
            if app.GenerateSweepTypeButton.Value == 0
                app.GenerateToneTypeButton.Value = 1;
                return
            end
            app.GenerateSweepTypeButton.Value = 0;
            app.GenerateToneTypeButton.Value = 1;

            % Disable End Freq, Sweep Type options,
            % Also rename Start Frequency to just Frequency
            app.FrequencyHzLabel.Text = "Frequency (Hz)";
            app.EndFrequencyHzLabel.Enable = "off";
            app.EndFrequencyHzSpinner.Enable = "off";
            app.SweepFunctionLabel.Enable = "off";
            app.SweepFnDropDown.Enable = "off";
        end

        % Value changed function: GenerateSweepTypeButton
        function GenerateSweepTypeButtonValueChanged(app, event)
            if app.GenerateToneTypeButton.Value == 0
                app.GenerateSweepTypeButton.Value = 1;
                return
            end
            app.GenerateToneTypeButton.Value = 0;
            app.GenerateSweepTypeButton.Value = 1;

            % Enable End Freq, Sweep Type options,
            % Also rename Frequency to Start Frequency
            app.FrequencyHzLabel.Text = "Start Frequency (Hz)";
            app.EndFrequencyHzLabel.Enable = "on";
            app.EndFrequencyHzSpinner.Enable = "on";
            app.SweepFunctionLabel.Enable = "on";
            app.SweepFnDropDown.Enable = "on";
        end

        % Button pushed function: PlaybackRefreshListsButton
        function PlaybackRefreshListsButtonPushed(app, event)
            request = matlab.net.http.RequestMessage;
            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', 'list', '?json=true');
            app.PlaybackFromFilesLogs.Value = strcat('GET ', uri);
            try
                getList = send(request, uri);
            catch ME
                disp(ME)
                fprintf('Cannot send GET request. Is the audio server running and reachable?\n\n')
                app.PlaybackFromFilesLogs.Value = 'Cannot send GET request. Please check the Command Window for more information.';
                return
            end

            list = getList.Body.Data;
            % Update AudioFilesList and PlaylistsList
            app.AudioFilesList = list.audio;
            app.PlaylistsList = list.playlist;
            
            % Update the current active Filter Dropdown
            if app.FilterAudioFilesButton.Value == 1
                app.FromFileDropDown.Items = app.AudioFilesList;
                app.FromFileDropDown.Value = app.AudioFilesList(1);
            elseif app.FilterPlaylistsButton.Value == 1
                app.FromFileDropDown.Items = app.PlaylistsList;
                app.FromFileDropDown.Value = app.PlaylistsList(1);
            end

            app.PlaybackFromFilesLogs.Value = append('Status: ', string(getList.StatusLine), newline, jsonencode(list, "PrettyPrint", true));
        end

        % Value changed function: PlayButtonFromFiles
        function PlayButtonFromFilesValueChanged(app, event)
            app.PlayButtonFromFiles.Enable = "off";
            drawnow

            if app.FilterAudioFilesButton.Value == 1
                requestType = 'play';
            elseif app.FilterPlaylistsButton.Value == 1
                requestType = 'playlist';
            end
            
            request = matlab.net.http.RequestMessage;
            audio = app.FromFileDropDown.Value;

            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', requestType, '/', audio, '?time=');

            timeMicro = string(posixtime(datetime('now', TimeZone = 'local', Format = 'yyyy-MM-dd HH:mm:ss.SSSSSS'))*1000000);
            uri = strcat(uri, timeMicro, '000');
            app.PlaybackFromFilesLogs.Value = append('GET ', uri);
            drawnow
            try
                response = send(request, uri);
            catch ME
                disp(ME)
                fprintf('Cannot send GET request. Is the audio server running and reachable?\n\n')
                app.PlaybackFromFilesLogs.Value = 'Cannot send GET request. Please check the Command Window for more information.';
                return
            end

            app.PlaybackFromFilesLogs.Value = append('Status: ', string(response.StatusLine), newline, jsonencode(response.Body.Data, "PrettyPrint", true));

            app.PlayButtonFromFiles.Value = false;
            app.PlayButtonFromFiles.Enable = "on";
        end

        % Value changed function: PlayButtonGenerateAudio
        function PlayButtonGenerateAudioValueChanged(app, event)
            app.PlayButtonGenerateAudio.Enable = "off";
            drawnow

            if app.GenerateToneTypeButton.Value == 1
                requestType = 'tone';
                data = strcat(num2str(app.FrequencyHzSpinner.Value), '/', num2str(app.DurationmsSpinner.Value), '/', ...
                    num2str(app.AmplitudedBSpinner.Value), '/', num2str(app.SampleRateHzSpinner.Value));
            elseif app.GenerateSweepTypeButton.Value == 1
                requestType = 'sweep';
                data = strcat(lower(app.SweepFnDropDown.Value), '/', num2str(app.FrequencyHzSpinner.Value), '/', ...
                    num2str(app.EndFrequencyHzSpinner.Value), '/', num2str(app.DurationmsSpinner.Value), '/', ...
                    num2str(app.AmplitudedBSpinner.Value), '/', num2str(app.SampleRateHzSpinner.Value));
            end
            request = matlab.net.http.RequestMessage;

            uri = strcat(strip(app.ServerIPPortEditField.Value,'right','/'), '/', requestType, '/', data, '?time=');

            timeMicro = string(posixtime(datetime('now', TimeZone = 'local', Format = 'yyyy-MM-dd HH:mm:ss.SSSSSS'))*1000000);
            uri = strcat(uri, timeMicro, '000', '&edge=', num2str(app.EdgesmsSpinner.Value));
            app.PlaybackGenerateAudioLog.Value = append('GET ', uri);
            drawnow
            try
                response = send(request, uri);
            catch ME
                disp(ME)
                fprintf('Cannot send GET request. Is the audio server running and reachable?\n\n')
                app.PlaybackGenerateAudioLog.Value = 'Cannot send GET request. Please check the Command Window for more information.';
                return
            end

            app.PlaybackGenerateAudioLog.Value = append('Status: ', string(response.StatusLine), newline, jsonencode(response.Body.Data, "PrettyPrint", true));

            app.PlayButtonGenerateAudio.Value = false;
            app.PlayButtonGenerateAudio.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioServerClientGUIUIFigure = uifigure('Visible', 'off');
            app.AudioServerClientGUIUIFigure.Name = 'Audio Server | Client GUI';
            app.TabGroup = uitabgroup(app.AudioServerClientGUIUIFigure);
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Tooltip = {''};
            app.PlaybackTab.Title = 'Playback';
            app.GeneratePanel = uipanel(app.PlaybackTab);
            app.GeneratePanel.Tooltip = {''};
            app.GeneratePanel.Enable = 'off';
            app.GeneratePanel.Title = '  Generate...';
            app.GridLayoutGenerate = uigridlayout(app.GeneratePanel);
            app.PlaybackGenerateAudioLog = uitextarea(app.GridLayoutGenerate);
            app.PlaybackGenerateAudioLog.Placeholder = 'Latest HTTP response will be shown here...';
            app.PlayButtonGenerateAudio = uibutton(app.GridLayoutGenerate, 'state');
            app.PlayButtonGenerateAudio.ValueChangedFcn = createCallbackFcn(app, @PlayButtonGenerateAudioValueChanged, true);
            app.PlayButtonGenerateAudio.Tooltip = {'Send the request to the server to start playing the audio. You should not send any other request while the audio is playing.'; ''; 'While playing, the audio cannot be stopped until finished, unless the server is terminated.'};
            app.PlayButtonGenerateAudio.Text = '▷  Play';
            app.SweepFnDropDown = uidropdown(app.GridLayoutGenerate);
            app.SweepFnDropDown.Items = {'Linear', 'Quadratic', 'Logarithmic', 'Hyperbolic'};
            app.SweepFnDropDown.Enable = 'off';
            app.SweepFnDropDown.Value = 'Linear';
            app.SweepFunctionLabel = uilabel(app.GridLayoutGenerate);
            app.SweepFunctionLabel.Enable = 'off';
            app.SweepFunctionLabel.Text = 'Sweep Function    ';
            app.EdgesmsHelp = uilabel(app.GridLayoutGenerate);
            app.EdgesmsHelp.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsHelp.Text = '? ';
            app.DurationmsSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.DurationmsSpinnerLabel.Text = 'Duration (ms)';
            app.AmplitudedBSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.AmplitudedBSpinnerLabel.Text = 'Amplitude (dB)';
            app.SampleRateHzSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.SampleRateHzSpinnerLabel.Text = 'Sample Rate (Hz)';
            app.EdgesmsSpinnerLabel = uilabel(app.GridLayoutGenerate);
            app.EdgesmsSpinnerLabel.Text = 'Edges (ms)';
            app.EdgesmsSpinner = uispinner(app.GridLayoutGenerate);
            app.EdgesmsSpinner.RoundFractionalValues = 'on';
            app.EdgesmsSpinner.ValueDisplayFormat = '%.0f';
            app.EdgesmsSpinner.Tooltip = {'The rising and falling edges of the tone or sweep. In other words, how long for the audio to fade in and out.'; ''; 'A positive Edges value will add the rising and falling duration to the specified Duration. The actual duration of the audio would then be (Duration + 2 * Edges).'; ''; 'A negative Edges value will make the rising and falling duration inclusive. The specified Duration will remain unchanged. The duration the audio is played at the specified Amplitude would then be (Duration - 2 * Edges).'; ''; 'If Edges is negative, the absolute value of Edges must be less than or equal to (Duration / 2).'; ''; 'The amplitude ramp for rising and falling is linear on the linear amplitude scale (0-1).'; ''; ''; 'Set Edges = 0 to play without fading in and out.'};
            app.EdgesmsSpinner.Placeholder = 'Must be an integer, negative or positive.';
            app.SampleRateHzSpinner = uispinner(app.GridLayoutGenerate);
            app.SampleRateHzSpinner.Step = 4000;
            app.SampleRateHzSpinner.Limits = [4000 1000000];
            app.SampleRateHzSpinner.RoundFractionalValues = 'on';
            app.SampleRateHzSpinner.ValueDisplayFormat = '%.0f';
            app.SampleRateHzSpinner.Placeholder = 'Must be a positive integer.';
            app.SampleRateHzSpinner.Value = 192000;
            app.AmplitudedBSpinner = uispinner(app.GridLayoutGenerate);
            app.AmplitudedBSpinner.Limits = [-300 300];
            app.AmplitudedBSpinner.ValueDisplayFormat = '%.3f';
            app.AmplitudedBSpinner.Value = 60;
            app.DurationmsSpinner = uispinner(app.GridLayoutGenerate);
            app.DurationmsSpinner.Step = 100;
            app.DurationmsSpinner.Limits = [0 Inf];
            app.DurationmsSpinner.RoundFractionalValues = 'on';
            app.DurationmsSpinner.ValueDisplayFormat = '%.0f';
            app.DurationmsSpinner.Placeholder = 'Must be a positive integer.';
            app.DurationmsSpinner.Value = 1000;
            app.EndFrequencyHzSpinner = uispinner(app.GridLayoutGenerate);
            app.EndFrequencyHzSpinner.Step = 100;
            app.EndFrequencyHzSpinner.Limits = [0 250000];
            app.EndFrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.EndFrequencyHzSpinner.Enable = 'off';
            app.EndFrequencyHzSpinner.Value = 14080;
            app.EndFrequencyHzLabel = uilabel(app.GridLayoutGenerate);
            app.EndFrequencyHzLabel.Enable = 'off';
            app.EndFrequencyHzLabel.Text = 'End Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.GridLayoutGenerate);
            app.FrequencyHzSpinner.Step = 100;
            app.FrequencyHzSpinner.Limits = [0 250000];
            app.FrequencyHzSpinner.ValueDisplayFormat = '%.3f';
            app.FrequencyHzSpinner.Value = 440;
            app.FrequencyHzLabel = uilabel(app.GridLayoutGenerate);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.GenerateTypePanel = uipanel(app.GridLayoutGenerate);
            app.GenerateTypePanel.Title = 'Type';
            app.GridLayoutGenerateType = uigridlayout(app.GenerateTypePanel);
            app.GenerateSweepTypeButton = uibutton(app.GridLayoutGenerateType, 'state');
            app.GenerateSweepTypeButton.ValueChangedFcn = createCallbackFcn(app, @GenerateSweepTypeButtonValueChanged, true);
            app.GenerateSweepTypeButton.Text = 'Sweep';
            app.GenerateToneTypeButton = uibutton(app.GridLayoutGenerateType, 'state');
            app.GenerateToneTypeButton.ValueChangedFcn = createCallbackFcn(app, @GenerateToneTypeButtonValueChanged, true);
            app.GenerateToneTypeButton.Text = 'Sine Tone';
            app.GenerateToneTypeButton.Value = true;
            app.GeneratePanelInfo = uilabel(app.GridLayoutGenerate);
            app.GeneratePanelInfo.Tooltip = {'Generate the pure tone or sweep and play it immediately.'; ''; 'The audio will be generated for every call, even if the same tone or sweep is requested multiple times. If performance and speed is a concern, Create the audio file first and play it directly.'};
            app.GeneratePanelInfo.Text = '? ';
            app.FromFilesPanel = uipanel(app.PlaybackTab);
            app.FromFilesPanel.Title = '  From Files';
            app.GridLayoutFromFiles = uigridlayout(app.FromFilesPanel);
            app.PlaybackFromFilesLogs = uitextarea(app.GridLayoutFromFiles);
            app.PlaybackFromFilesLogs.Editable = 'off';
            app.PlaybackFromFilesLogs.Placeholder = 'Latest HTTP response will be shown here...';
            app.PlayButtonFromFiles = uibutton(app.GridLayoutFromFiles, 'state');
            app.PlayButtonFromFiles.ValueChangedFcn = createCallbackFcn(app, @PlayButtonFromFilesValueChanged, true);
            app.PlayButtonFromFiles.Tooltip = {'Send the request to the server to start playing the audio. You should not send any other request while the audio is playing.'; ''; 'While playing, the audio cannot be stopped until finished, unless the server is terminated.'};
            app.PlayButtonFromFiles.Text = '▷  Play';
            app.FromFileDropDown = uidropdown(app.GridLayoutFromFiles);
            app.FilterPanel = uipanel(app.GridLayoutFromFiles);
            app.FilterPanel.Title = 'Filter...';
            app.GridLayoutPlaybackFilter = uigridlayout(app.FilterPanel);
            app.FilterPlaylistsButton = uibutton(app.GridLayoutPlaybackFilter, 'state');
            app.FilterPlaylistsButton.ValueChangedFcn = createCallbackFcn(app, @FilterPlaylistsButtonValueChanged, true);
            app.FilterPlaylistsButton.Text = 'Playlists';
            app.FilterAudioFilesButton = uibutton(app.GridLayoutPlaybackFilter, 'state');
            app.FilterAudioFilesButton.ValueChangedFcn = createCallbackFcn(app, @FilterAudioFilesButtonValueChanged, true);
            app.FilterAudioFilesButton.Text = 'Audio Files';
            app.FilterAudioFilesButton.Value = true;
            app.PlaybackRefreshListsButton = uibutton(app.GridLayoutFromFiles, 'push');
            app.PlaybackRefreshListsButton.ButtonPushedFcn = createCallbackFcn(app, @PlaybackRefreshListsButtonPushed, true);
            app.PlaybackRefreshListsButton.Text = '↺  Refresh Lists';
            app.FromFilesPanelInfo = uilabel(app.GridLayoutFromFiles);
            app.FromFilesPanelInfo.Tooltip = {'Play available audio files (.wav, .flac, .mp3,...) or playlists (.txt) on the server.'; ''; 'The available options are shown in the dropdown list. To view the comprehensive list of available audio files and playlists, go to the Information tab.'};
            app.FromFilesPanelInfo.Text = '? ';
            app.PlaybackTypeSwitch = uiswitch(app.PlaybackTab, 'slider');
            app.PlaybackTypeSwitch.Items = {'From Files', 'Generate'};
            app.PlaybackTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaybackTypeSwitchValueChanged, true);
            app.PlaybackTypeSwitch.Value = 'From Files';
            app.CreateTab = uitab(app.TabGroup);
            app.CreateTab.Title = 'Create';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayoutSettings = uigridlayout(app.SettingsTab);
            app.ServerIPPortLabel = uilabel(app.GridLayoutSettings);
            app.ServerIPPortLabel.Text = 'Server IP : Port  ';
            app.ServerIPPortEditField = uieditfield(app.GridLayoutSettings, 'text');
            app.ServerIPPortEditField.Placeholder = 'http://127.0.0.1:5055';
            app.ServerIPPortEditField.Value = 'http://127.0.0.1:5055';
            % Show the figure after all components are created
            app.AudioServerClientGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClientGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AudioServerClientGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AudioServerClientGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioServerClientGUIUIFigure)
        end
    end
end